classdef LUPACheckoutApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        HWRLShareButton           matlab.ui.control.Button
        DriveTempCEditField       matlab.ui.control.NumericEditField
        DriveTempCEditFieldLabel  matlab.ui.control.Label
        TsLabel                   matlab.ui.control.Label
        eCATstateEditField        matlab.ui.control.NumericEditField
        eCATstateEditFieldLabel   matlab.ui.control.Label
        CurrentLimitSpinnerLabel  matlab.ui.control.Label
        ClearFaultsButton         matlab.ui.control.Button
        EnableDriveButton         matlab.ui.control.Button
        StopStartButton           matlab.ui.control.Button
        MotorTempCEditField       matlab.ui.control.NumericEditField
        MotorTempCEditFieldLabel  matlab.ui.control.Label
        StatusWordEditField       matlab.ui.control.NumericEditField
        StatusWordEditFieldLabel  matlab.ui.control.Label
        CtrlWordEditField         matlab.ui.control.NumericEditField
        CtrlWordEditFieldLabel    matlab.ui.control.Label
        TimeEditField             matlab.ui.control.NumericEditField
        TimeEditFieldLabel        matlab.ui.control.Label
        ModelInfoLabel            matlab.ui.control.Label
        ReferenceControl          matlab.ui.container.TabGroup
        ReferenceTab              matlab.ui.container.Tab
        ScaleSpinner              matlab.ui.control.Spinner
        ScaleSpinnerLabel         matlab.ui.control.Label
        SignalDropDown            matlab.ui.control.DropDown
        SignalDropDownLabel       matlab.ui.control.Label
        ControlTab                matlab.ui.container.Tab
        StiffnessSpinner          matlab.ui.control.Spinner
        StiffnessSpinnerLabel     matlab.ui.control.Label
        DampingSpinner            matlab.ui.control.Spinner
        DampingSpinnerLabel       matlab.ui.control.Label
        SourceDropDown            matlab.ui.control.DropDown
        SourceDropDownLabel       matlab.ui.control.Label
        EnableCtrlButton          matlab.ui.control.Button
        CurrentLimitSpinner       matlab.ui.control.Spinner
        SaveInfoPanel             matlab.ui.container.Panel
        TrialSpinner              matlab.ui.control.Spinner
        TrialSpinnerLabel         matlab.ui.control.Label
        ExperimentEditField       matlab.ui.control.EditField
        ExperimentEditFieldLabel  matlab.ui.control.Label
        ProjectEditField          matlab.ui.control.EditField
        ProjectEditFieldLabel     matlab.ui.control.Label
        RightPanel                matlab.ui.container.Panel
        TabGroup                  matlab.ui.container.TabGroup
        DriveTab                  matlab.ui.container.Tab
        VelocityAxes              matlab.ui.control.UIAxes
        PositionAxes              matlab.ui.control.UIAxes
        CurrentAxes               matlab.ui.control.UIAxes
        TorqueTransducerAxes      matlab.ui.control.UIAxes
        SensorsTab                matlab.ui.container.Tab
        IMUAxes                   matlab.ui.control.UIAxes
        DrawWireAxes              matlab.ui.control.UIAxes
        LoadCellsAxes             matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        buildDir % build directory
        mdlName = 'LUPA_Checkout2'
        tg  % the realtime target
        tgName % realtime target name
        Ts
        hInst   % handle to the instrument object
        startIcon = fullfile('icons','run_24.png')
        stopIcon = fullfile('icons','stop_24.png')
    end

    methods (Access = private)

        function results = setupInstrumentation(app)
            app.tg.removeAllInstruments;

            % create instrument object
            app.hInst = slrealtime.Instrument(app.mdlName);

            % scalar signals
            app.hInst.connectScalar(app.TimeEditField,[app.mdlName,'/Initialization/Clock'],1);
            app.hInst.connectScalar(app.CtrlWordEditField,[app.mdlName,'/Initialization/Ctrl_Word'],1);
            app.hInst.connectScalar(app.StatusWordEditField,[app.mdlName,'/Initialization/driveStatus'],1);
            app.hInst.connectScalar(app.eCATstateEditField,[app.mdlName,'/Initialization/eCATstate'],1);
            app.hInst.connectScalar(app.MotorTempCEditField,[app.mdlName,'/LUPAInputs/temp_degC'],1);
            app.hInst.connectScalar(app.DriveTempCEditField,[app.mdlName,'/LUPAInputs/ELMOInputs/driveTemp_degC'],1);

            % scope signals Drive tab
            app.hInst.connectLine(app.CurrentAxes,[app.mdlName,'/LUPAInputs/ELMOInputs/current_A'],1);
            app.hInst.connectLine(app.CurrentAxes,[app.mdlName,'/Outputs/Target_A'],1);
            app.hInst.connectLine(app.PositionAxes,[app.mdlName,'/LUPAInputs/ELMOInputs/pos_rad'],1);
            app.hInst.connectLine(app.VelocityAxes,[app.mdlName,'/LUPAInputs/ELMOInputs/vel_radpers'],1);
%             app.hInst.connectLine(app.VelocityAxes,[app.mdlName,'/LUPAInputs/ELMOInputs/vel_filt_radpers'],1);
            app.hInst.connectLine(app.TorqueTransducerAxes,[app.mdlName,'/LUPAInputs/TTorque_Nm'],1);
            app.hInst.connectLine(app.TorqueTransducerAxes,[app.mdlName,'/LUPAInputs/ELMOInputs/torque_Nm'],1);

            % scope signals Sensors tab
            app.hInst.connectLine(app.LoadCellsAxes,[app.mdlName,'/LUPAInputs/LCtop'],1);
            app.hInst.connectLine(app.LoadCellsAxes,[app.mdlName,'/LUPAInputs/LCbot'],1);
            app.hInst.connectLine(app.DrawWireAxes,[app.mdlName,'/Shore ADC/sp1'],1);
            app.hInst.connectLine(app.DrawWireAxes,[app.mdlName,'/Shore ADC/sp2'],1);
            app.hInst.connectLine(app.IMUAxes,[app.mdlName,'/LUPAInputs/IMU/thetaPitch'],1);
            app.hInst.connectLine(app.IMUAxes,[app.mdlName,'/LUPAInputs/IMU/accX'],1);
            app.hInst.connectLine(app.IMUAxes,[app.mdlName,'/LUPAInputs/IMU/accZ'],1);
            

            % connect callback to update axes
            % app.hInst.connectCallback(@app.regularCallback);
            app.DrawWireAxes.YLim = [-0.5 0.5];

            % add the instrument object to the app
            app.tg.addInstrument(app.hInst)


            legend(app.VelocityAxes,'Reported');
            legend(app.TorqueTransducerAxes,'Torque Transducer','Motor Drive');
            legend(app.CurrentAxes,'Measured','Target');
            legend(app.PositionAxes,'Measured');
            legend(app.LoadCellsAxes,'LCtop','LCbot');
            legend(app.DrawWireAxes,'sp1','sp2');
            legend(app.IMUAxes,'thetaPitch','accX','accZ');

%             reloadData(app.tg.Stimulation, 'Ramps', 'waveform')

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message','Running the Digital Twin Startup function ...');
            d.Value = 0.1;
            d.Message = 'Loading workspace data';
            app.mdlName = evalin('base','mdlName');
            app.buildDir = evalin('base','buildDir');
            app.tgName = evalin('base','tgName');
            app.Ts = evalin('base','Ts');
            modelVersion = evalin('base','mdlVersion');
            app.ModelInfoLabel.Text = ['Model: ',app.mdlName,' Version ', modelVersion];
            app.TsLabel.Text = ['Ts = ',num2str(app.Ts),' s'];
            d.Value = 0.2;
            d.Message = ['Connecting to the RT target',app.tgName, ' ...'];

            app.tg = slrealtime(app.tgName);

            d.Value = 0.5;
            d.Message = ['Loading the model ',app.mdlName,' ...'];
            app.tg.stop;
            app.tg.load(fullfile(app.buildDir,app.mdlName));
            app.tg.removeAllInstruments();

            app.StopStartButton.Icon = app.startIcon;
            app.StopStartButton.Text = 'Start Twin';

            app.EnableDriveButton.Text = 'Enable Drive';
            app.EnableDriveButton.BackgroundColor = 'Red';

            app.EnableCtrlButton.Text = 'Enable Ctrl';
            app.EnableCtrlButton.BackgroundColor = 'Red';

            app.HWRLShareButton.Text = 'Push to Share';
            app.HWRLShareButton.BackgroundColor = 'Green';
            assignin('base','shareStatus','yes')

            app.ClearFaultsButton.Text = 'Clear Faults';

            app.setupInstrumentation;

            d.close();

%             units = get(groot, 'Units'); % backup Units
%             set(groot, 'Units', 'pixels');
%             app.UIFigure.Position = get(groot, 'ScreenSize');
%             set(groot, 'Units', units); % restore original units
        end

        % Button pushed function: StopStartButton
        function StopStartButtonPushed(app, event)
            if strcmp(app.StopStartButton.Icon,app.startIcon)
                app.setupInstrumentation;
                app.tg.start('ReloadOnStop',true,'AutoImportFileLog',false);
                app.StopStartButton.Icon = app.stopIcon;
                app.StopStartButton.Text = 'Stop';
            else
                app.tg.stop;
                app.StopStartButton.Icon = app.startIcon;
                app.StopStartButton.Text = 'Start';
                run('postProcess.m');

            end
        end

        % Button pushed function: EnableDriveButton
        function EnableDriveButtonPushed(app, event)
            if strcmp(app.EnableDriveButton.Text,'Enable Drive')
                app.tg.setparam([app.mdlName,'/Initialization/enableDrive'],'Value',1);
                app.EnableDriveButton.Text = 'Drive Enabled';
                app.EnableDriveButton.BackgroundColor = 'Green';

            else
                app.tg.setparam([app.mdlName,'/Initialization/enableDrive'],'Value',0);
                app.EnableDriveButton.Text = 'Enable Drive';
                app.EnableDriveButton.BackgroundColor = 'Red';
            end
        end

        % Button pushed function: ClearFaultsButton
        function ClearFaultsButtonPushed(app, event)
            if strcmp(app.ClearFaultsButton.Text,'Clear Faults')
                app.tg.setparam([app.mdlName,'/Initialization/clearFaults'],'Value',1);
                app.ClearFaultsButton.Text = 'Faults Clearing';
                pause(1);
                app.tg.setparam([app.mdlName,'/Initialization/clearFaults'],'Value',0);
                app.ClearFaultsButton.Text = 'Clear Faults';

            end
        end

        % Value changed function: ScaleSpinner
        function ScaleSpinnerValueChanged(app, event)
            value = app.ScaleSpinner.Value;
            app.tg.setparam([app.mdlName,'/Outputs/Target_A'],'Gain',value)
        end

        % Value changed function: SignalDropDown
        function SignalDropDownValueChanged(app, event)
            value = app.SignalDropDown.Value;
            if strcmp(value,'None')
                app.tg.setparam([app.mdlName,'/inputSwitch'],'Value',1)
            elseif strcmp(value,'Ramps')
                app.tg.setparam([app.mdlName,'/inputSwitch'],'Value',2)
            elseif strcmp(value,'Sine')
                app.tg.setparam([app.mdlName,'/inputSwitch'],'Value',3)
            elseif strcmp(value,'WN1')
                app.tg.setparam([app.mdlName,'/inputSwitch'],'Value',4)
            elseif strcmp(value,'WN2')
                app.tg.setparam([app.mdlName,'/inputSwitch'],'Value',5)
            elseif strcmp(value,'WN3')
                app.tg.setparam([app.mdlName,'/inputSwitch'],'Value',6)
            elseif strcmp(value,'Chirp')
                app.tg.setparam([app.mdlName,'/inputSwitch'],'Value',7)

            end
        end

        % Button pushed function: EnableCtrlButton
        function EnableCtrlButtonPushed(app, event)
            if strcmp(app.EnableCtrlButton.Text,'Enable Ctrl')
                app.tg.setparam([app.mdlName,'/enableControl'],'Value',1);
                app.EnableCtrlButton.Text = 'Ctrl Enabled';
                app.EnableCtrlButton.BackgroundColor = 'Green';

            else
                app.tg.setparam([app.mdlName,'/enableControl'],'Value',0);
                app.EnableCtrlButton.Text = 'Enable Ctrl';
                app.EnableCtrlButton.BackgroundColor = 'Red';
            end
        end

        % Value changed function: DampingSpinner
        function DampingSpinnerValueChanged(app, event)
            value = app.DampingSpinner.Value;
            app.tg.setparam([app.mdlName,'/damping'],'Value',value)
        end

        % Value changed function: StiffnessSpinner
        function StiffnessSpinnerValueChanged(app, event)
            value = app.StiffnessSpinner.Value;
            app.tg.setparam([app.mdlName,'/stiffness'],'Value',value)          
        end

        % Value changed function: CurrentLimitSpinner
        function CurrentLimitSpinnerValueChanged(app, event)
            value = app.CurrentLimitSpinner.Value;
            app.tg.setparam([app.mdlName,'/currentLimit'],'Value',value)
        end

        % Button pushed function: HWRLShareButton
        function HWRLShareButtonPushed(app, event)
            if strcmp(app.HWRLShareButton.Text,'Push to Share')
                app.HWRLShareButton.Text = 'No Push to Share';
                app.HWRLShareButton.BackgroundColor = 'Red';
                assignin('base','shareStatus','no')
            else
                app.HWRLShareButton.Text = 'Push to Share';
                app.HWRLShareButton.BackgroundColor = 'Green';
                assignin('base','shareStatus','yes')
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {686, 686};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SaveInfoPanel = uipanel(app.LeftPanel);
            app.SaveInfoPanel.Title = 'Save Info';
            app.ProjectEditFieldLabel = uilabel(app.SaveInfoPanel);
            app.ProjectEditFieldLabel.Text = 'Project';
            app.ProjectEditField = uieditfield(app.SaveInfoPanel, 'text');
            app.ProjectEditField.Placeholder = 'DryLUPA';
            app.ProjectEditField.Value = 'DryLUPA';
            app.ExperimentEditFieldLabel = uilabel(app.SaveInfoPanel);
            app.ExperimentEditFieldLabel.Text = 'Experiment';
            app.ExperimentEditField = uieditfield(app.SaveInfoPanel, 'text');
            app.TrialSpinnerLabel = uilabel(app.SaveInfoPanel);
            app.TrialSpinnerLabel.Text = 'Trial';
            app.TrialSpinner = uispinner(app.SaveInfoPanel);
            app.CurrentLimitSpinner = uispinner(app.LeftPanel);
            app.CurrentLimitSpinner.Limits = [0 16.2];
            app.CurrentLimitSpinner.ValueChangedFcn = createCallbackFcn(app, @CurrentLimitSpinnerValueChanged, true);
            app.CurrentLimitSpinner.Value = 1;
            app.ReferenceControl = uitabgroup(app.LeftPanel);
            app.ReferenceTab = uitab(app.ReferenceControl);
            app.ReferenceTab.Title = 'Reference';
            app.SignalDropDownLabel = uilabel(app.ReferenceTab);
            app.SignalDropDownLabel.Text = 'Signal';
            app.SignalDropDown = uidropdown(app.ReferenceTab);
            app.SignalDropDown.Items = {'None', 'Ramps', 'Sine', 'WN1', 'WN2', 'WN3', 'Chirp'};
            app.SignalDropDown.ValueChangedFcn = createCallbackFcn(app, @SignalDropDownValueChanged, true);
            app.SignalDropDown.Value = 'None';
            app.ScaleSpinnerLabel = uilabel(app.ReferenceTab);
            app.ScaleSpinnerLabel.Text = 'Scale';
            app.ScaleSpinner = uispinner(app.ReferenceTab);
            app.ScaleSpinner.Step = 0.1;
            app.ScaleSpinner.Limits = [0 Inf];
            app.ScaleSpinner.ValueChangedFcn = createCallbackFcn(app, @ScaleSpinnerValueChanged, true);
            app.ControlTab = uitab(app.ReferenceControl);
            app.ControlTab.Title = 'Control';
            app.EnableCtrlButton = uibutton(app.ControlTab, 'push');
            app.EnableCtrlButton.ButtonPushedFcn = createCallbackFcn(app, @EnableCtrlButtonPushed, true);
            app.EnableCtrlButton.Text = 'Enable Ctrl';
            app.SourceDropDownLabel = uilabel(app.ControlTab);
            app.SourceDropDownLabel.Text = 'Source';
            app.SourceDropDown = uidropdown(app.ControlTab);
            app.SourceDropDown.Items = {'Manual', 'Excel'};
            app.SourceDropDown.Value = 'Manual';
            app.DampingSpinnerLabel = uilabel(app.ControlTab);
            app.DampingSpinnerLabel.Text = 'Damping';
            app.DampingSpinner = uispinner(app.ControlTab);
            app.DampingSpinner.Step = 0.1;
            app.DampingSpinner.ValueChangedFcn = createCallbackFcn(app, @DampingSpinnerValueChanged, true);
            app.StiffnessSpinnerLabel = uilabel(app.ControlTab);
            app.StiffnessSpinnerLabel.Text = 'Stiffness';
            app.StiffnessSpinner = uispinner(app.ControlTab);
            app.StiffnessSpinner.Step = 0.1;
            app.StiffnessSpinner.ValueChangedFcn = createCallbackFcn(app, @StiffnessSpinnerValueChanged, true);
            app.ModelInfoLabel = uilabel(app.LeftPanel);
            app.ModelInfoLabel.Text = 'Model Info';
            app.TimeEditFieldLabel = uilabel(app.LeftPanel);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.CtrlWordEditFieldLabel = uilabel(app.LeftPanel);
            app.CtrlWordEditFieldLabel.Text = 'Ctrl Word';
            app.CtrlWordEditField = uieditfield(app.LeftPanel, 'numeric');
            app.StatusWordEditFieldLabel = uilabel(app.LeftPanel);
            app.StatusWordEditFieldLabel.Text = 'Status Word';
            app.StatusWordEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MotorTempCEditFieldLabel = uilabel(app.LeftPanel);
            app.MotorTempCEditFieldLabel.Text = 'Motor Temp °C';
            app.MotorTempCEditField = uieditfield(app.LeftPanel, 'numeric');
            app.StopStartButton = uibutton(app.LeftPanel, 'push');
            app.StopStartButton.ButtonPushedFcn = createCallbackFcn(app, @StopStartButtonPushed, true);
            app.StopStartButton.Text = 'StopStart';
            app.EnableDriveButton = uibutton(app.LeftPanel, 'push');
            app.EnableDriveButton.ButtonPushedFcn = createCallbackFcn(app, @EnableDriveButtonPushed, true);
            app.EnableDriveButton.Text = 'Enable Drive';
            app.ClearFaultsButton = uibutton(app.LeftPanel, 'push');
            app.ClearFaultsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearFaultsButtonPushed, true);
            app.ClearFaultsButton.Text = 'ClearFaults';
            app.CurrentLimitSpinnerLabel = uilabel(app.LeftPanel);
            app.CurrentLimitSpinnerLabel.Text = 'Current Limit';
            app.eCATstateEditFieldLabel = uilabel(app.LeftPanel);
            app.eCATstateEditFieldLabel.Text = 'eCAT state';
            app.eCATstateEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TsLabel = uilabel(app.LeftPanel);
            app.TsLabel.Text = 'Ts';
            app.DriveTempCEditFieldLabel = uilabel(app.LeftPanel);
            app.DriveTempCEditFieldLabel.Text = 'Drive Temp °C ';
            app.DriveTempCEditField = uieditfield(app.LeftPanel, 'numeric');
            app.HWRLShareButton = uibutton(app.LeftPanel, 'push');
            app.HWRLShareButton.ButtonPushedFcn = createCallbackFcn(app, @HWRLShareButtonPushed, true);
            app.HWRLShareButton.Text = 'HWRLShare';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.DriveTab = uitab(app.TabGroup);
            app.DriveTab.Title = 'Drive';
            app.TorqueTransducerAxes = uiaxes(app.DriveTab);
            title(app.TorqueTransducerAxes, 'Torque')
            ylabel(app.TorqueTransducerAxes, 'Nm')
            zlabel(app.TorqueTransducerAxes, 'Z')
            app.CurrentAxes = uiaxes(app.DriveTab);
            title(app.CurrentAxes, 'Motor Current')
            ylabel(app.CurrentAxes, 'A')
            zlabel(app.CurrentAxes, 'Z')
            app.PositionAxes = uiaxes(app.DriveTab);
            title(app.PositionAxes, 'Motor Position')
            ylabel(app.PositionAxes, 'rad')
            zlabel(app.PositionAxes, 'Z')
            app.VelocityAxes = uiaxes(app.DriveTab);
            title(app.VelocityAxes, 'Motor Velocity')
            xlabel(app.VelocityAxes, 'time (s)')
            ylabel(app.VelocityAxes, 'rad/s')
            zlabel(app.VelocityAxes, 'Z')
            app.SensorsTab = uitab(app.TabGroup);
            app.SensorsTab.Title = 'Sensors';
            app.LoadCellsAxes = uiaxes(app.SensorsTab);
            title(app.LoadCellsAxes, 'Load Cells')
            ylabel(app.LoadCellsAxes, 'N')
            zlabel(app.LoadCellsAxes, 'Z')
            app.DrawWireAxes = uiaxes(app.SensorsTab);
            title(app.DrawWireAxes, 'Draw Wire')
            ylabel(app.DrawWireAxes, 'm')
            zlabel(app.DrawWireAxes, 'Z')
            app.IMUAxes = uiaxes(app.SensorsTab);
            title(app.IMUAxes, 'IMU')
            xlabel(app.IMUAxes, 'time(s)')
            zlabel(app.IMUAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LUPACheckoutApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end